classdef Videovigilancia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SistemadeVigilanciaUIFigure     matlab.ui.Figure
        Label_porcentaje                matlab.ui.control.Label
        ConexinconcmaraPanel            matlab.ui.container.Panel
        ConectarButton                  matlab.ui.control.Button
        CalidadButtonGroup              matlab.ui.container.ButtonGroup
        BajaButton                      matlab.ui.control.RadioButton
        MediaButton                     matlab.ui.control.RadioButton
        AltaButton                      matlab.ui.control.RadioButton
        DesconectarButton               matlab.ui.control.Button
        CmaraDropDownLabel              matlab.ui.control.Label
        CmaraDropDown                   matlab.ui.control.DropDown
        AjusteSwitchLabel               matlab.ui.control.Label
        AjusteSwitch                    matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        PruebadesensibilidadPanel       matlab.ui.container.Panel
        MovimientomedioLabel            matlab.ui.control.Label
        MovimientomedioTextArea         matlab.ui.control.TextArea
        BloquesactivosLabel             matlab.ui.control.Label
        BloquesactivosTextArea          matlab.ui.control.TextArea
        SensibilidadKnobLabel           matlab.ui.control.Label
        SensibilidadKnob                matlab.ui.control.Knob
        IniciarpruebaButton             matlab.ui.control.Button
        DetenerpruebaButton             matlab.ui.control.Button
        ConfiguracindeldetectorPanel    matlab.ui.container.Panel
        AjusteporzonasButton            matlab.ui.control.Button
        CargarconfigButton              matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
        FiltartamaodeobjetoCheckBox     matlab.ui.control.CheckBox
        FiltrardireccinCheckBox         matlab.ui.control.CheckBox
        nguloLabel_2                    matlab.ui.control.Label
        AngleSpinner                    matlab.ui.control.Spinner
        Label_3                         matlab.ui.control.Label
        tolerancy                       matlab.ui.control.Spinner
        ImageVector                     matlab.ui.control.Image
        nguloLabel                      matlab.ui.control.Label
        EstablecerhorarioCheckBox       matlab.ui.control.CheckBox
        sLabel                          matlab.ui.control.Label
        HorainicioEditFieldLabel        matlab.ui.control.Label
        HorainicioEditField             matlab.ui.control.EditField
        HorafinalEditFieldLabel         matlab.ui.control.Label
        HorafinalEditField              matlab.ui.control.EditField
        NmerodeinstantneasLabel         matlab.ui.control.Label
        NmerodeinstantneasEditField     matlab.ui.control.NumericEditField
        TiempoentrealarmasEditFieldLabel  matlab.ui.control.Label
        TiempoentrealarmasEditField     matlab.ui.control.NumericEditField
        NotificacinTelegramSwitchLabel  matlab.ui.control.Label
        NotificacinTelegramSwitch       matlab.ui.control.Switch
        MOVIMIENTODETECTADOLabel        matlab.ui.control.Label
        DetectorarmadoLampLabel         matlab.ui.control.Label
        DetectorarmadoLamp              matlab.ui.control.Lamp
        ArmardetectorButton             matlab.ui.control.Button
        DesarmarButton                  matlab.ui.control.Button
        ControlManualPanel              matlab.ui.container.Panel
        CompensacinluzdefonfoSwitchLabel  matlab.ui.control.Label
        CompensacinluzdefonfoSwitch     matlab.ui.control.Switch
        BrilloSliderLabel               matlab.ui.control.Label
        BrilloSlider                    matlab.ui.control.Slider
        ExposicinSliderLabel            matlab.ui.control.Label
        ExposicinSlider                 matlab.ui.control.Slider
        ContarsteSliderLabel            matlab.ui.control.Label
        ContarsteSlider                 matlab.ui.control.Slider
        EnfoqueSliderLabel              matlab.ui.control.Label
        EnfoqueSlider                   matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        HueSliderLabel                  matlab.ui.control.Label
        HueSlider                       matlab.ui.control.Slider
        SaturacinSliderLabel            matlab.ui.control.Label
        SaturacinSlider                 matlab.ui.control.Slider
        DetalleSliderLabel              matlab.ui.control.Label
        DetalleSlider                   matlab.ui.control.Slider
        WBSliderLabel                   matlab.ui.control.Label
        WBSlider                        matlab.ui.control.Slider
        RestaurarconfiguracinpordefectoButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Image_Help                      matlab.ui.control.Image
        Image_Info                      matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        videosource;            %Estructura de datos de fuente de vídeo(control de cámara)
        running=1;              %Variable que marca el estado de ejecución
    end
    
    properties (Access = public)
        VideoAdaptor = 1;       %Adaptador de vídeo utilizado por sistema(por defecto 1 = 'WindowsVideo')
        vidobj;                 %Objeto cámara de vídeo
        res_pro                 %Vector Reslución de procesamiento
        res_show = [360 640];   %Vector Resolución con la que se muestra por pantalla
        res_full;               %Vector Resolución a la que se capturan las imágenes
        blocksize = 10;         %Tamaño lado de subdivisión en procesado
        grid = ones(24,32);     %Matriz que contienen la sensibilidad por zonas
        selected_camera=0;      %Entero en el que se almacena la cámara seleccionada por el usuario
        detector_state;         %Variable que indica el estado del detector
    end
    
    
    methods (Access = private)
        %Métodos privados de la clase
        function [img] = SafeGetSnapshot(app)
            try
                img = getsnapshot(app.vidobj);
            catch ME
                disp('ERROR - No se pudo capturar imagen')
                try
                    img=uint8(zeros(app.res_full(1), app.res_full(2), 3));
                catch ME
                    disp('Aplicación cerrada')
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AjusteporzonasButton.Enable = false;
            app.CargarconfigButton.Enable = false;
            app.FiltartamaodeobjetoCheckBox.Enable = false;
            app.Spinner.Enable = false;
            app.FiltrardireccinCheckBox.Enable = false;
            app.AngleSpinner.Enable = false;
            app.tolerancy.Enable = false;
            app.ImageVector.Enable = false;
            app.MovimientomedioTextArea.Enable = false;
            app.BloquesactivosTextArea.Enable = false;
            app.SensibilidadKnob.Enable = false;
            app.IniciarpruebaButton.Enable = false;
            app.DetenerpruebaButton.Enable = false;
            app.ArmardetectorButton.Enable = false;
            app.DesarmarButton.Enable = false;
            app.EstablecerhorarioCheckBox.Enable = false;
            app.HorafinalEditField.Enable = false;
            app.HorainicioEditField.Enable = false;
            app.NmerodeinstantneasEditField.Enable = false;
            app.TiempoentrealarmasEditField.Enable = false;
            app.DesconectarButton.Enable = false;
            app.AjusteSwitch.Enable = false;
            
            %============= INICIALIZACIÓN ===================================
            %Código ejecutado en el arranque de la aplicación.
            
%             javaFrame = get(app.SistemadeVigilanciaUIFigure,'JavaFrame');
% %           javaFrame.setFigureIcon(javax.swing.ImageIcon('iconapp.ico'));
%             S1=pwd; S2='\iconapp.ico'; S={[S1 S2]};
%             jIcon=javax.swing.ImageIcon(S);
%             javaFrame.setFigureIcon(jIcon);

            app.TextArea.Value = {'--------------';'Videovigilancia Log';'--------------'};
            
            app.CmaraDropDown.Items = device_name_list(app.VideoAdaptor);%Asignación de nombres de
            % dispositivos a selector de cámaras de la interfaz
            
%             try
                
%             catch ME
%                 disp('No esixte el fichero de configuración, se queda con configuración por defecto')
%             end
            
            
            %Ocultar ventana ajuste manual
            app.SistemadeVigilanciaUIFigure.Position = [100,100,703,769];
            app.ControlManualPanel.Visible = false;
            
            void_visor = imread('void_visor.jpg');
            I = imshow(imresize(void_visor,app.res_show), 'Parent', app.UIAxes);%Mostrar imagen en visor
            %Inicialización del tamaño de ejes para ajuste con el sensor
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            
            %====================================================================================================
            
            
            arrow = imread("arrowGUI.jpg");
           arrow = imrotate(arrow, 180, 'crop');
            app.ImageVector.ImageSource = arrow;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)

            device_list = device_name_list(app.VideoAdaptor); %Obteniendo lista de cámaras
            app.selected_camera = (sum(strcmp(device_list, app.CmaraDropDown.Value).*([1:numel(device_list)])));
            

            if app.BajaButton == true
                [app.vidobj, app.videosource] = config_cam(app.selected_camera,'low','rgb');
                app.res_full = [360 480];
                app.res_pro =  [360 480];
                disp(['Calidad baja seleccionada' num2str(app.res_full)]);
            elseif app.MediaButton == true
                [app.vidobj, app.videosource] = config_cam(app.selected_camera,'medium','rgb');
                app.res_full = [480 640];
                app.res_pro =  [480 640];
                disp(['Calidad media seleccionada' num2str(app.res_full)]);
            else
                [app.vidobj, app.videosource] = config_cam(app.selected_camera,'high','rgb');
                app.res_full = [720 1280];
                app.res_pro =  [360 640];
                disp(['Calidad alta seleccionada' num2str(app.res_full)]);
            end
            
%             cleanup = onCleanup(@()clean_videoObject(app.vidobj));%Evitar dejar recurso abierto
            
            img1 = getsnapshot(app.vidobj);
            imshow(imresize(img1,app.res_show), 'Parent', app.UIAxes);%Mostrar imagen en visor
            
            app.BajaButton.Enable=false;
            app.MediaButton.Enable=false;
            app.AltaButton.Enable=false;
            app.CmaraDropDown.Enable=false;
            app.ConectarButton.Enable = false;


            app.AjusteporzonasButton.Enable = true;
            app.CargarconfigButton.Enable = true;
            app.FiltartamaodeobjetoCheckBox.Enable = true;
            app.Spinner.Enable = true;
            app.FiltrardireccinCheckBox.Enable = true;
            app.AngleSpinner.Enable = true;
            app.tolerancy.Enable = true;
            app.ImageVector.Enable = true;
            app.MovimientomedioTextArea.Enable = true;
            app.BloquesactivosTextArea.Enable = true;
            app.SensibilidadKnob.Enable = true;
            app.IniciarpruebaButton.Enable = true;
            app.DetenerpruebaButton.Enable = true;
            app.ArmardetectorButton.Enable = true;
            app.DesarmarButton.Enable = true;
            app.EstablecerhorarioCheckBox.Enable = true;
            app.HorafinalEditField.Enable = true;
            app.HorainicioEditField.Enable = true;
            app.NmerodeinstantneasEditField.Enable = true;
            app.TiempoentrealarmasEditField.Enable = true;
            app.DesconectarButton.Enable = true;
            app.AjusteSwitch.Enable = true;
            
            
            visor = imread('not_running.jpg');
            I = imshow(imresize(visor,app.res_show), 'Parent', app.UIAxes);%Mostrar imagen en visor


        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            app.BajaButton.Enable=true;
            app.MediaButton.Enable=true;
            app.AltaButton.Enable=true;
            app.CmaraDropDown.Enable=true;
            app.ConectarButton.Enable = true;
            
            app.AjusteporzonasButton.Enable = false;
            app.CargarconfigButton.Enable = false;
            app.FiltartamaodeobjetoCheckBox.Enable = false;
            app.Spinner.Enable = false;
            app.FiltrardireccinCheckBox.Enable = false;
            app.AngleSpinner.Enable = false;
            app.tolerancy.Enable = false;
            app.ImageVector.Enable = false;
            app.MovimientomedioTextArea.Enable = false;
            app.BloquesactivosTextArea.Enable = false;
            app.SensibilidadKnob.Enable = false;
            app.IniciarpruebaButton.Enable = false;
            app.DetenerpruebaButton.Enable = false;
            app.ArmardetectorButton.Enable = false;
            app.DesarmarButton.Enable = false;
            app.EstablecerhorarioCheckBox.Enable = false;
            app.HorafinalEditField.Enable = false;
            app.HorainicioEditField.Enable = false;
            app.NmerodeinstantneasEditField.Enable = false;
            app.TiempoentrealarmasEditField.Enable = false;
            app.AjusteSwitch.Enable = false;
            
            app.running=0;
            stop(app.vidobj);
            void_visor = imread('void_visor.jpg');
            I = imshow(imresize(void_visor,app.res_show), 'Parent', app.UIAxes);%Mostrar imagen en visor
        end

        % Value changed function: SensibilidadKnob
        function SensibilidadKnobValueChanged(app, event)
            value = app.SensibilidadKnob.Value;
            app.Label.Text= num2str(value);
        end

        % Button pushed function: AjusteporzonasButton
        function AjusteporzonasButtonPushed(app, event)
            app.running=0;
            res_show_grid = app.res_show;
            grid_control = advanced_grid_control(app.vidobj,res_show_grid);
        end

        % Close request function: SistemadeVigilanciaUIFigure
        function SistemadeVigilanciaUIFigureCloseRequest(app, event)
            %===================CLOSE REQUEST=============================>
            %Esta función es ejecutada cuando se cierra la aplaicación por
            %medio del botón x de la ventana de la aplicación.
            
            
            try
                stop(app.vidobj)
            catch ME
                disp('Video object ya cerrado antes de salida')
            end
            save_configuration(app); %Gruardar configuración detector antes de salir
            delete(app)
             %=============================================================>
        end

        % Button pushed function: IniciarpruebaButton
        function IniciarpruebaButtonPushed(app, event)
            try
                start(app.vidobj);
            catch ME
                disp('ERROR- Objeto vidobj ya inicializado en entrada de IniciarpruebaButtonPushed')
            end
            
            app.running=1;
            img1 = getsnapshot(app.vidobj);
            
            imshow(imresize(img1,app.res_show), 'Parent', app.UIAxes);%Mostrar imagen en visor
            
            while app.running==1
                pause(0.1)
                img2= getsnapshot(app.vidobj);
                img1sub = submuestreo(img1, app.res_pro);
                img2sub = submuestreo(img2, app.res_pro);
                [moving, counter, MSEmatrix] = MovementShape(img1sub, img2sub, app.SensibilidadKnob.Value, app.blocksize);
                img_visor = imresize(img2, app.res_show);
                img_visor(:,:,1) = img_visor(:,:,1) +imresize(moving, app.res_show,"Method","nearest").*50;
                imshow(img_visor, 'Parent', app.UIAxes);%Mostrar imagen en visor
                meanMSE=mean(mean(MSEmatrix));
                app.MovimientomedioTextArea.Value = num2str(meanMSE);
                app.BloquesactivosTextArea.Value = num2str(counter);

                img1=img2;
            end
            stop(app.vidobj);
        end

        % Button pushed function: ArmardetectorButton
        function ArmardetectorButtonPushed(app, event)
            DateString = datestr(datetime('now'));
            texto = app.TextArea.Value;
            texto{end+1}=['[' DateString ']' ' DETECTOR ARMADO'];
            app.TextArea.Value = texto;
            app.detector_state = 1; %Estado de detector aramdao
            app.running=0;          %Detener prueba de sensibilidad
            
            app.AjusteporzonasButton.Enable = false;
            app.CargarconfigButton.Enable = false;
            app.FiltartamaodeobjetoCheckBox.Enable = false;
            app.Spinner.Enable = false;
            app.FiltrardireccinCheckBox.Enable = false;
            app.AngleSpinner.Enable = false;
            app.tolerancy.Enable = false;
            app.ImageVector.Enable = false;
            app.MovimientomedioTextArea.Enable = false;
            app.BloquesactivosTextArea.Enable = false;
            app.SensibilidadKnob.Enable = false;
            app.IniciarpruebaButton.Enable = false;
            app.DetenerpruebaButton.Enable = false;
            
            try  %Intentar arranque de objeto de càmara
                start(app.vidobj);
            catch ME
                disp(['Ya iniciado->' ME.message])
            end
            
            app.grid=load('grid.mat').grid_final; %Carga de la matriz de sensibilidades señeccionada en selector
            img1 = getsnapshot(app.vidobj);
            img_prev = img1; %Imagen previa a detección
            
            imshow(imresize(img1,app.res_show), 'Parent', app.UIAxes); %Mostrar imagen en visor
            
            movement_bool=0;        %Flag de control que indica si en la anterior iteración se había detectado movimiento
            previous_movement=0;    %Variable para almacenar el movimiento detectado en anterior interación
            angle_precision = 20;   % +- Precisión de la dirección de movimiento en grados
            detection = false;
            
            %Bucle del detector
            while app.detector_state==1
                pause(0.2);
    
                L= 0;
                sizes = 0;
                vector_alerta = 0;
                vector = 0;
                x=0;
                y=0;
                
                img2= getsnapshot(app.vidobj);
                
                img1sub = submuestreo(img1, app.res_pro);
                img2sub = submuestreo(img2, app.res_pro);
                [moving, counter, MSEmatrix] = MovementShape(img1sub, img2sub, app.grid, app.blocksize);
                
                if and(app.EstablecerhorarioCheckBox.Value == true, hour_inside_interval(app.HorainicioEditField.Value,app.HorafinalEditField.Value)==false)
                    not_detected(app,img2);
                elseif and(numel(moving)*0.05 < counter , counter < numel(moving)*0.9)
                    %% 1.-Detección por número de bloques
                    %$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$
                    if app.FiltartamaodeobjetoCheckBox.Value
                        %% 2.-Deteccón de objetos
                        %#########################################################################################################
                        [L, num,sizes] = countandsize(moving);
                        if(num > 0) && max(sizes) > app.Spinner.Value/100
                            if app.FiltrardireccinCheckBox.Value 
                                %% 3.-Deteccón de dirección de movimiento
                                %**************************************************************************************************
                                if movement_bool
                                    [vector,x,y] = vector_movimiento(previous_movement,moving);%Obtención del vector del movimiento
                                    if angle_inside_interval(app,vector,app.AngleSpinner.Value ,app.tolerancy.Value)
                                        vector_alerta = vector;
                                        movement_detected(app,img2);
                                        detection = true;
                                    else
                                        not_detected(app,img2);
                                    end
                                end
                            else
                                %**************************************************************************************************
                                movement_detected(app,img2);
                                 
                                DateString = datestr(datetime('now'));
                                texto = app.TextArea.Value;
                                texto{end+1}=['[' DateString ']' ' Detector activado por tamaño mayor del'  num2str(max(sizes))  '% de la imagen'];
                                app.TextArea.Value = texto;
                                
                                detection = true;
                            end
                            movement_bool = 1;
                            previous_movement = moving;
                        else
                            movement_bool = 0;
                            not_detected(app,img2);
                        end
                        %##############################################################################################################
                    else
                        movement_detected(app,img2); 
                        DateString = datestr(datetime('now'));
                        texto = app.TextArea.Value;
                        texto{end+1}=['[' DateString ']'  ' Detector activado por '  num2str(counter)  ' bloques activos' '(' num2str(counter*100/numel(MSEmatrix)) '%)'];
                        app.TextArea.Value = texto;
                        
                        detection = true;
                    end
                    %$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$$
                else
                    not_detected(app,img2);
                end
               
                
                if detection
                    detection = false;
                    n_frames = app.NmerodeinstantneasEditField.Value;
                    notify_detection(app.vidobj, n_frames, moving, img1, img2, img_prev, MSEmatrix, counter, L, sizes, vector_alerta,x,y);
                    pause(10);
                    not_detected(app,img2);
                    img1= getsnapshot(app.vidobj);
                    pause(0.1)
                    img2= getsnapshot(app.vidobj);
                end
                
                img_prev = img1;
                img1 = img2;
            end
        end

        % Button pushed function: DetenerpruebaButton
        function DetenerpruebaButtonPushed(app, event)
           app.running=0;
        end

        % Button pushed function: DesarmarButton
        function DesarmarButtonPushed(app, event)
            DateString = datestr(datetime('now'));
            texto = app.TextArea.Value;
            texto{end+1}=['[' DateString ']' ' DETECTOR DESARMADO'];
            app.TextArea.Value = texto;
            
            app.detector_state = 0;
            app.DetectorarmadoLamp.Color = 'red';
            app.MOVIMIENTODETECTADOLabel.Visible = false;
            
            app.AjusteporzonasButton.Enable = true;
            app.CargarconfigButton.Enable = true;
            app.FiltartamaodeobjetoCheckBox.Enable = true;
            app.Spinner.Enable = true;
            app.FiltrardireccinCheckBox.Enable = true;
            app.AngleSpinner.Enable = true;
            app.tolerancy.Enable = true;
            app.ImageVector.Enable = true;
            app.MovimientomedioTextArea.Enable = true;
            app.BloquesactivosTextArea.Enable = true;
            app.SensibilidadKnob.Enable = true;
            app.IniciarpruebaButton.Enable = true;
            app.DetenerpruebaButton.Enable = true;
            app.ArmardetectorButton.Enable = true;
            app.DesarmarButton.Enable = true;
            app.EstablecerhorarioCheckBox.Enable = true;
            app.HorafinalEditField.Enable = true;
            app.HorainicioEditField.Enable = true;
            app.NmerodeinstantneasEditField.Enable = true;
            app.TiempoentrealarmasEditField.Enable = true;
            app.DesconectarButton.Enable = true;
            app.AjusteSwitch.Enable = true;
        end

        % Value changed function: CompensacinluzdefonfoSwitch
        function CompensacinluzdefonfoSwitchValueChanged(app, event)
            stop(app.vidobj);
            value = app.CompensacinluzdefonfoSwitch.Value;
            if numel(value) == 3
                app.videosource.BacklightCompensation = 'off';
            else
                app.videosource.BacklightCompensation = 'on';
            end
            start(app.vidobj);
        end

        % Value changed function: BrilloSlider
        function BrilloSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.BrilloSlider.Value;
            value = round(value);
            app.videosource.Brightness = value;
            start(app.vidobj);
        end

        % Value changed function: ExposicinSlider
        function ExposicinSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.ExposicinSlider.Value;
            value = round(value);
            try
                app.videosource.ExposureMode = 'manual';
                app.videosource.Exposure = value;
            catch ME
                disp('ERROR - Parámetro exposición no configurable');
            end
            start(app.vidobj);
        end

        % Value changed function: ContarsteSlider
        function ContarsteSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.ContarsteSlider.Value;
            value = round(value);
            app.videosource.Contrast = value;
            start(app.vidobj);
        end

        % Value changed function: EnfoqueSlider
        function EnfoqueSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.EnfoqueSlider.Value;
            value = round(value);
            try
                app.videosource.FocusMode = 'manual';
            app.videosource.Focus = value;
            catch ME
                disp('ERROR - Parámetro enfoque no configurable');
            end
            
            start(app.vidobj);
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.GammaSlider.Value;
            value = round(value);
            app.videosource.Gamma = value;
            start(app.vidobj);
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.HueSlider.Value;
            value = round(value);
            app.videosource.Hue = value;
            start(app.vidobj);
        end

        % Value changed function: SaturacinSlider
        function SaturacinSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.SaturacinSlider.Value;
            value = round(value);
            app.videosource.Saturation = value;
            start(app.vidobj);
        end

        % Value changed function: DetalleSlider
        function DetalleSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.DetalleSlider.Value;
            value = round(value);
            app.videosource.Sharpness = value;
            start(app.vidobj);
        end

        % Value changed function: WBSlider
        function WBSliderValueChanged(app, event)
            stop(app.vidobj);
            value = app.WBSlider.Value;
            value = round(value);
            app.videosource.WhiteBalanceMode = 'manual';
            app.videosource.WhiteBalance = value;
            start(app.vidobj);
        end

        % Value changed function: AjusteSwitch
        function AjusteSwitchValueChanged(app, event)
            value = app.AjusteSwitch.Value;
            if numel(value) == 2
                app.SistemadeVigilanciaUIFigure.Position = [app.SistemadeVigilanciaUIFigure.Position(1),app.SistemadeVigilanciaUIFigure.Position(2),927,769];
                app.ControlManualPanel.Visible = true;
            else
                app.SistemadeVigilanciaUIFigure.Position = [app.SistemadeVigilanciaUIFigure.Position(1),app.SistemadeVigilanciaUIFigure.Position(2),703,769];
                app.ControlManualPanel.Visible = false;
            end
            app.BrilloSlider.Limits = double(propinfo(app.videosource,'Brightness').ConstraintValue);            
            app.ContarsteSlider.Limits = double(propinfo(app.videosource,'Contrast').ConstraintValue);
            try
                app.GammaSlider.Limits = double(propinfo(app.videosource,'Gamma').ConstraintValue);
            catch ME
                app.GammaSlider.Enable = false;
            end
            
            try
                app.HueSlider.Limits = double(propinfo(app.videosource,'Hue').ConstraintValue);                 
            catch ME
                app.HueSlider.Enable = false;
            end
           
            app.SaturacinSlider.Limits = double(propinfo(app.videosource,'Saturation').ConstraintValue);            
            app.DetalleSlider.Limits = double(propinfo(app.videosource,'Sharpness').ConstraintValue);
            app.ExposicinSlider.Limits = double(propinfo(app.videosource,'Exposure').ConstraintValue);
            app.WBSlider.Limits = double(propinfo(app.videosource,'WhiteBalance').ConstraintValue);
            app.BrilloSlider.Value = double(propinfo(app.videosource,'Brightness').DefaultValue);            
            app.ContarsteSlider.Value = double(propinfo(app.videosource,'Contrast').DefaultValue);                  
            try
                app.GammaSlider.Value = double(propinfo(app.videosource,'Gamma').DefaultValue); 
            catch ME 
            end
            try
                 app.HueSlider.Value = double(propinfo(app.videosource,'Hue').DefaultValue); 
            catch ME  
            end   
            app.SaturacinSlider.Value = double(propinfo(app.videosource,'Saturation').DefaultValue);            
            app.DetalleSlider.Value = double(propinfo(app.videosource,'Sharpness').DefaultValue);
            app.ExposicinSlider.Value = double(app.videosource.Exposure);
            app.WBSlider.Value = double(app.videosource.WhiteBalance);
        end

        % Button pushed function: CargarconfigButton
        function CargarconfigButtonPushed(app, event)
             load_configuration(app);
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            value = app.AngleSpinner.Value;
            arrow = imread("arrowGUI.jpg");
            arrow = imrotate(arrow, value*(-1), 'crop');
            arrow=uint8(arrow == 0)*38+arrow;
            app.ImageVector.ImageSource = arrow;
        end

        % Button pushed function: 
        % RestaurarconfiguracinpordefectoButton
        function RestaurarconfiguracinpordefectoButtonPushed(app, event)
            stop(app.vidobj);
            try       
            app.videosource.FocusMode = 'auto';
            catch ME
                disp('Warning - Problema al restaurar config')
                app.EnfoqueSlider.Enable = false;
            end
            app.videosource.WhiteBalanceMode = 'auto';
            app.CompensacinluzdefonfoSwitch.Value = 'On';
            app.videosource.ExposureMode = 'auto';
            app.videosource.BacklightCompensation = 'on';
            app.videosource.Brightness =  double(propinfo(app.videosource,'Brightness').DefaultValue);
            app.videosource.Contrast = double(propinfo(app.videosource,'Contrast').DefaultValue);
            try
                app.videosource.Gamma = double(propinfo(app.videosource,'Gamma').DefaultValue);
            catch ME   
            end
            try
                app.videosource.Hue = double(propinfo(app.videosource,'Hue').DefaultValue);
            catch ME
            end
            
            app.videosource.Saturation = double(propinfo(app.videosource,'Saturation').DefaultValue);
            app.videosource.Sharpness = double(propinfo(app.videosource,'Sharpness').DefaultValue);
            start(app.vidobj);
            pause(1);
            try
                app.EnfoqueSlider.Value = app.videosource.Focus;
                app.EnfoqueSlider.Enable = false;
            catch ME 
            end
            try
                app.GammaSlider.Value = double(propinfo(app.videosource,'Gamma').DefaultValue);          
                app.HueSlider.Value = double(propinfo(app.videosource,'Hue').DefaultValue);  
            catch ME
            end
            app.BrilloSlider.Value = double(propinfo(app.videosource,'Brightness').DefaultValue);            
            app.ContarsteSlider.Value = double(propinfo(app.videosource,'Contrast').DefaultValue);                  
          
            app.SaturacinSlider.Value = double(propinfo(app.videosource,'Saturation').DefaultValue);            
            app.DetalleSlider.Value = double(propinfo(app.videosource,'Sharpness').DefaultValue);
            app.ExposicinSlider.Value = double(app.videosource.Exposure);
            app.WBSlider.Value = double(app.videosource.WhiteBalance);
            
        end

        % Value changed function: EstablecerhorarioCheckBox
        function EstablecerhorarioCheckBoxValueChanged(app, event)
            value = app.EstablecerhorarioCheckBox.Value;
                app.HorafinalEditField.Enable =value;
                app.HorafinalEditFieldLabel.Enable = value;
        end

        % Size changed function: ConexinconcmaraPanel
        function ConexinconcmaraPanelSizeChanged(app, event)
            position = app.ConexinconcmaraPanel.Position;
            
        end

        % Image clicked function: Image_Info
        function Image_InfoClicked(app, event)
            information;
        end

        % Image clicked function: Image_Help
        function Image_HelpClicked(app, event)
            open('Manual Sistema de Videovigilancia.pdf');
        end

        % Value changed function: NotificacinTelegramSwitch
        function NotificacinTelegramSwitchValueChanged(app, event)
            value = app.NotificacinTelegramSwitch.Value;
            if numel(value) == 2
                BotTelegramConfig;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SistemadeVigilanciaUIFigure = uifigure('Visible', 'off');
            app.SistemadeVigilanciaUIFigure.Name = 'Sistema de Vigilancia';
            app.SistemadeVigilanciaUIFigure.CloseRequestFcn = createCallbackFcn(app, @SistemadeVigilanciaUIFigureCloseRequest, true);
            app.Label_porcentaje = uilabel(app.SistemadeVigilanciaUIFigure);
            app.Label_porcentaje.Visible = 'off';
            app.Label_porcentaje.Text = 'Label_porcentaje';
            app.ConexinconcmaraPanel = uipanel(app.SistemadeVigilanciaUIFigure);
            app.ConexinconcmaraPanel.Title = 'Conexión con cámara';
            app.ConexinconcmaraPanel.SizeChangedFcn = createCallbackFcn(app, @ConexinconcmaraPanelSizeChanged, true);
            app.ConectarButton = uibutton(app.ConexinconcmaraPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.CalidadButtonGroup = uibuttongroup(app.ConexinconcmaraPanel);
            app.CalidadButtonGroup.Title = 'Calidad';
            app.BajaButton = uiradiobutton(app.CalidadButtonGroup);
            app.BajaButton.Text = 'Baja';
            app.MediaButton = uiradiobutton(app.CalidadButtonGroup);
            app.MediaButton.Text = 'Media';
            app.AltaButton = uiradiobutton(app.CalidadButtonGroup);
            app.AltaButton.Text = 'Alta';
            app.AltaButton.Value = true;
            app.DesconectarButton = uibutton(app.ConexinconcmaraPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.CmaraDropDownLabel = uilabel(app.ConexinconcmaraPanel);
            app.CmaraDropDownLabel.Text = 'Cámara';
            app.CmaraDropDown = uidropdown(app.ConexinconcmaraPanel);
            app.CmaraDropDown.Items = {};
            app.CmaraDropDown.Value = {};
            app.AjusteSwitchLabel = uilabel(app.ConexinconcmaraPanel);
            app.AjusteSwitchLabel.Text = 'Ajuste';
            app.AjusteSwitch = uiswitch(app.ConexinconcmaraPanel, 'slider');
            app.AjusteSwitch.ValueChangedFcn = createCallbackFcn(app, @AjusteSwitchValueChanged, true);
            app.UIAxes = uiaxes(app.SistemadeVigilanciaUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PruebadesensibilidadPanel = uipanel(app.SistemadeVigilanciaUIFigure);
            app.PruebadesensibilidadPanel.Title = 'Prueba de sensibilidad';
            app.MovimientomedioLabel = uilabel(app.PruebadesensibilidadPanel);
            app.MovimientomedioLabel.Text = {'Movimiento'; ' medio'};
            app.MovimientomedioTextArea = uitextarea(app.PruebadesensibilidadPanel);
            app.BloquesactivosLabel = uilabel(app.PruebadesensibilidadPanel);
            app.BloquesactivosLabel.Text = {'Bloques '; 'activos'};
            app.BloquesactivosTextArea = uitextarea(app.PruebadesensibilidadPanel);
            app.SensibilidadKnobLabel = uilabel(app.PruebadesensibilidadPanel);
            app.SensibilidadKnobLabel.Text = 'Sensibilidad';
            app.SensibilidadKnob = uiknob(app.PruebadesensibilidadPanel, 'continuous');
            app.SensibilidadKnob.Limits = [0 10];
            app.SensibilidadKnob.ValueChangedFcn = createCallbackFcn(app, @SensibilidadKnobValueChanged, true);
            app.SensibilidadKnob.Value = 5;
            app.IniciarpruebaButton = uibutton(app.PruebadesensibilidadPanel, 'push');
            app.IniciarpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarpruebaButtonPushed, true);
            app.IniciarpruebaButton.Text = {'Iniciar '; 'prueba'};
            app.DetenerpruebaButton = uibutton(app.PruebadesensibilidadPanel, 'push');
            app.DetenerpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerpruebaButtonPushed, true);
            app.DetenerpruebaButton.Text = {'Detener '; 'prueba'};
            app.ConfiguracindeldetectorPanel = uipanel(app.SistemadeVigilanciaUIFigure);
            app.ConfiguracindeldetectorPanel.Title = 'Configuración del detector';
            app.AjusteporzonasButton = uibutton(app.ConfiguracindeldetectorPanel, 'push');
            app.AjusteporzonasButton.ButtonPushedFcn = createCallbackFcn(app, @AjusteporzonasButtonPushed, true);
            app.AjusteporzonasButton.Text = {'Ajuste'; 'por zonas'};
            app.CargarconfigButton = uibutton(app.ConfiguracindeldetectorPanel, 'push');
            app.CargarconfigButton.ButtonPushedFcn = createCallbackFcn(app, @CargarconfigButtonPushed, true);
            app.CargarconfigButton.Text = 'Cargar config';
            app.Label_2 = uilabel(app.ConfiguracindeldetectorPanel);
            app.Label_2.Text = '%';
            app.Spinner = uispinner(app.ConfiguracindeldetectorPanel);
            app.Spinner.Limits = [5 100];
            app.Spinner.Value = 10;
            app.FiltartamaodeobjetoCheckBox = uicheckbox(app.ConfiguracindeldetectorPanel);
            app.FiltartamaodeobjetoCheckBox.Text = 'Filtar tamaño de objeto';
            app.FiltrardireccinCheckBox = uicheckbox(app.ConfiguracindeldetectorPanel);
            app.FiltrardireccinCheckBox.Text = 'Filtrar dirección';
            app.nguloLabel_2 = uilabel(app.ConfiguracindeldetectorPanel);
            app.nguloLabel_2.Text = 'Ángulo';
            app.AngleSpinner = uispinner(app.ConfiguracindeldetectorPanel);
            app.AngleSpinner.Limits = [0 360];
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.AngleSpinner.Value = 180;
            app.Label_3 = uilabel(app.ConfiguracindeldetectorPanel);
            app.Label_3.Text = '°±';
            app.tolerancy = uispinner(app.ConfiguracindeldetectorPanel);
            app.tolerancy.Limits = [0 360];
            app.tolerancy.Value = 10;
            app.ImageVector = uiimage(app.ConfiguracindeldetectorPanel);
            app.ImageVector.ImageSource = 'arrowGUI.jpg';
            app.nguloLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.nguloLabel.Text = 'Ángulo';
            app.EstablecerhorarioCheckBox = uicheckbox(app.ConfiguracindeldetectorPanel);
            app.EstablecerhorarioCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstablecerhorarioCheckBoxValueChanged, true);
            app.EstablecerhorarioCheckBox.Text = 'Establecer horario';
            app.sLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.sLabel.Text = ' s';
            app.HorainicioEditFieldLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.HorainicioEditFieldLabel.Enable = 'off';
            app.HorainicioEditFieldLabel.Text = 'Hora inicio';
            app.HorainicioEditField = uieditfield(app.ConfiguracindeldetectorPanel, 'text');
            app.HorainicioEditField.Enable = 'off';
            app.HorainicioEditField.Value = '07:00';
            app.HorafinalEditFieldLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.HorafinalEditFieldLabel.Enable = 'off';
            app.HorafinalEditFieldLabel.Text = 'Hora final';
            app.HorafinalEditField = uieditfield(app.ConfiguracindeldetectorPanel, 'text');
            app.HorafinalEditField.Enable = 'off';
            app.HorafinalEditField.Value = '18:00';
            app.NmerodeinstantneasLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.NmerodeinstantneasLabel.Text = {'Número '; 'de instantáneas'};
            app.NmerodeinstantneasEditField = uieditfield(app.ConfiguracindeldetectorPanel, 'numeric');
            app.NmerodeinstantneasEditField.Limits = [1 Inf];
            app.NmerodeinstantneasEditField.Value = 10;
            app.TiempoentrealarmasEditFieldLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.TiempoentrealarmasEditFieldLabel.Text = {'Tiempo entre'; 'alarmas'};
            app.TiempoentrealarmasEditField = uieditfield(app.ConfiguracindeldetectorPanel, 'numeric');
            app.TiempoentrealarmasEditField.Limits = [1 Inf];
            app.TiempoentrealarmasEditField.Value = 10;
            app.NotificacinTelegramSwitchLabel = uilabel(app.ConfiguracindeldetectorPanel);
            app.NotificacinTelegramSwitchLabel.Text = {'Notificación '; 'Telegram'};
            app.NotificacinTelegramSwitch = uiswitch(app.ConfiguracindeldetectorPanel, 'slider');
            app.NotificacinTelegramSwitch.ValueChangedFcn = createCallbackFcn(app, @NotificacinTelegramSwitchValueChanged, true);
            app.MOVIMIENTODETECTADOLabel = uilabel(app.SistemadeVigilanciaUIFigure);
            app.MOVIMIENTODETECTADOLabel.Visible = 'off';
            app.MOVIMIENTODETECTADOLabel.Text = '¡MOVIMIENTO DETECTADO!';
            app.DetectorarmadoLampLabel = uilabel(app.SistemadeVigilanciaUIFigure);
            app.DetectorarmadoLampLabel.Text = {'Detector'; 'armado'};
            app.DetectorarmadoLamp = uilamp(app.SistemadeVigilanciaUIFigure);
            app.ArmardetectorButton = uibutton(app.SistemadeVigilanciaUIFigure, 'push');
            app.ArmardetectorButton.ButtonPushedFcn = createCallbackFcn(app, @ArmardetectorButtonPushed, true);
            app.ArmardetectorButton.Text = 'Armar detector';
            app.DesarmarButton = uibutton(app.SistemadeVigilanciaUIFigure, 'push');
            app.DesarmarButton.ButtonPushedFcn = createCallbackFcn(app, @DesarmarButtonPushed, true);
            app.DesarmarButton.Text = 'Desarmar';
            app.ControlManualPanel = uipanel(app.SistemadeVigilanciaUIFigure);
            app.ControlManualPanel.Title = 'Control Manual';
            app.CompensacinluzdefonfoSwitchLabel = uilabel(app.ControlManualPanel);
            app.CompensacinluzdefonfoSwitchLabel.Text = {'Compensación'; 'luz de fonfo'};
            app.CompensacinluzdefonfoSwitch = uiswitch(app.ControlManualPanel, 'slider');
            app.CompensacinluzdefonfoSwitch.ValueChangedFcn = createCallbackFcn(app, @CompensacinluzdefonfoSwitchValueChanged, true);
            app.BrilloSliderLabel = uilabel(app.ControlManualPanel);
            app.BrilloSliderLabel.Text = 'Brillo';
            app.BrilloSlider = uislider(app.ControlManualPanel);
            app.BrilloSlider.Limits = [-64 64];
            app.BrilloSlider.ValueChangedFcn = createCallbackFcn(app, @BrilloSliderValueChanged, true);
            app.ExposicinSliderLabel = uilabel(app.ControlManualPanel);
            app.ExposicinSliderLabel.Text = 'Exposición';
            app.ExposicinSlider = uislider(app.ControlManualPanel);
            app.ExposicinSlider.Limits = [-11 0];
            app.ExposicinSlider.ValueChangedFcn = createCallbackFcn(app, @ExposicinSliderValueChanged, true);
            app.ExposicinSlider.Value = -5.5;
            app.ContarsteSliderLabel = uilabel(app.ControlManualPanel);
            app.ContarsteSliderLabel.Text = 'Contarste';
            app.ContarsteSlider = uislider(app.ControlManualPanel);
            app.ContarsteSlider.ValueChangedFcn = createCallbackFcn(app, @ContarsteSliderValueChanged, true);
            app.ContarsteSlider.Value = 50;
            app.EnfoqueSliderLabel = uilabel(app.ControlManualPanel);
            app.EnfoqueSliderLabel.Text = 'Enfoque';
            app.EnfoqueSlider = uislider(app.ControlManualPanel);
            app.EnfoqueSlider.Limits = [0 56];
            app.EnfoqueSlider.ValueChangedFcn = createCallbackFcn(app, @EnfoqueSliderValueChanged, true);
            app.EnfoqueSlider.Value = 28;
            app.GammaSliderLabel = uilabel(app.ControlManualPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.ControlManualPanel);
            app.GammaSlider.Limits = [100 300];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.Value = 200;
            app.HueSliderLabel = uilabel(app.ControlManualPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.ControlManualPanel);
            app.HueSlider.Limits = [-100 100];
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.SaturacinSliderLabel = uilabel(app.ControlManualPanel);
            app.SaturacinSliderLabel.Text = 'Saturación';
            app.SaturacinSlider = uislider(app.ControlManualPanel);
            app.SaturacinSlider.ValueChangedFcn = createCallbackFcn(app, @SaturacinSliderValueChanged, true);
            app.SaturacinSlider.Value = 50;
            app.DetalleSliderLabel = uilabel(app.ControlManualPanel);
            app.DetalleSliderLabel.Text = 'Detalle';
            app.DetalleSlider = uislider(app.ControlManualPanel);
            app.DetalleSlider.ValueChangedFcn = createCallbackFcn(app, @DetalleSliderValueChanged, true);
            app.DetalleSlider.Value = 50;
            app.WBSliderLabel = uilabel(app.ControlManualPanel);
            app.WBSliderLabel.Text = 'W/B';
            app.WBSlider = uislider(app.ControlManualPanel);
            app.WBSlider.Limits = [2800 6500];
            app.WBSlider.ValueChangedFcn = createCallbackFcn(app, @WBSliderValueChanged, true);
            app.WBSlider.Value = 4650;
            app.RestaurarconfiguracinpordefectoButton = uibutton(app.ControlManualPanel, 'push');
            app.RestaurarconfiguracinpordefectoButton.ButtonPushedFcn = createCallbackFcn(app, @RestaurarconfiguracinpordefectoButtonPushed, true);
            app.RestaurarconfiguracinpordefectoButton.Text = {'Restaurar'; 'configuración'; 'por defecto'};
            app.Label = uilabel(app.SistemadeVigilanciaUIFigure);
            app.Label.Text = '0';
            app.Image_Help = uiimage(app.SistemadeVigilanciaUIFigure);
            app.Image_Help.ImageClickedFcn = createCallbackFcn(app, @Image_HelpClicked, true);
            app.Image_Help.ImageSource = 'help-butt.png';
            app.Image_Info = uiimage(app.SistemadeVigilanciaUIFigure);
            app.Image_Info.ImageClickedFcn = createCallbackFcn(app, @Image_InfoClicked, true);
            app.Image_Info.ImageSource = 'info-butt.png';
            app.TextArea = uitextarea(app.SistemadeVigilanciaUIFigure);
            % Show the figure after all components are created
            app.SistemadeVigilanciaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Videovigilancia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SistemadeVigilanciaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SistemadeVigilanciaUIFigure)
        end
    end
end