classdef advanced_grid_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AjusteporzonasUIFigure      matlab.ui.Figure
        LimpiarzonasButton          matlab.ui.control.Button
        MarcartodasButton           matlab.ui.control.Button
        flecha_arriba               matlab.ui.control.Button
        flecha_abajo                matlab.ui.control.Button
        flecha_izquierda            matlab.ui.control.Button
        flecha_derecha              matlab.ui.control.Button
        SensibilidadKnobLabel       matlab.ui.control.Label
        SensibilidadKnob            matlab.ui.control.Knob
        UIAxes                      matlab.ui.control.UIAxes
        TamaodeseleccinSliderLabel  matlab.ui.control.Label
        TamaodeseleccinSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public)
        video_object;       %Objeto cámara de vídeo
        grid_small;         %Vector con la versión reducida de la matriz de sensibilidades
        res_display;        %Vector con la resolución de representación de las imágenes
        smalles_zone = 20;  %Tamaño del lado del cuadrado de zona menor seleccionable 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vidobj, res_show)
            app.video_object = vidobj; %Asignación de valores utilizados por scripts externos a esta App
            app.res_display = res_show;
            
            start(vidobj);
            display = getsnapshot(vidobj);
            display_resized = imresize(display, res_show);
            I = imshow(display_resized, 'Parent', app.UIAxes);%Mostrar imagen en visor
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.grid_small = zeros(res_show./app.smalles_zone); 
        end

        % Button pushed function: LimpiarzonasButton
        function LimpiarzonasButtonPushed(app, event)
            app.grid_small(:,:) = 0;
            actualizar_fondo_avanzado;
        end

        % Button pushed function: MarcartodasButton
        function MarcartodasButtonPushed(app, event)
            app.grid_small(:,:) = app.SensibilidadKnob.Value;
            actualizar_fondo_avanzado;
        end

        % Button pushed function: flecha_izquierda
        function flecha_izquierdaPushed(app, event)
            app.grid_small(:,1:size(app.grid_small,2)/2) = app.SensibilidadKnob.Value;
            actualizar_fondo_avanzado;
        end

        % Button pushed function: flecha_arriba
        function flecha_arribaPushed(app, event)
            app.grid_small(1:size(app.grid_small,1)/2,:) = app.SensibilidadKnob.Value;
            actualizar_fondo_avanzado;
        end

        % Button pushed function: flecha_derecha
        function flecha_derechaButtonPushed(app, event)
            app.grid_small(:,size(app.grid_small,2)/2:size(app.grid_small,2)) = app.SensibilidadKnob.Value;
            actualizar_fondo_avanzado;
        end

        % Button pushed function: flecha_abajo
        function flecha_abajoButtonPushed(app, event)
            app.grid_small(size(app.grid_small,1)/2:size(app.grid_small,1),:) = app.SensibilidadKnob.Value;
            actualizar_fondo_avanzado;
        end

        % Close request function: AjusteporzonasUIFigure
        function AjusteporzonasUIFigureCloseRequest(app, event)
            %-->Función ejecutada al cerrar la ventana de la app
            Videovigilancia.grid = app.grid_small; %Envío de la matriz de sensibilidades a la app principal
            stop(app.video_object);
            grid_final = app.grid_small;
            save('grid.mat','grid_final');
            delete(app);
        end

        % Window button down function: AjusteporzonasUIFigure
        function AjusteporzonasUIFigureWindowButtonDown(app, event)
            %Esta función se ejecuta en cada evento de click izquierdo en
            %cualquier lugar de la ventana de aplicación.
            
            x= app.UIAxes.CurrentPoint;                 %Obtención de la posición del ratón relativa a los ejes
            row_adjust = abs(floor(x(1,2)/app.res_display(1)*app.res_display(1)/app.smalles_zone)+1);   %Ajsute de puntero a la fila correspondiente de la matriz de sensibilidades
            col_adjust = abs(floor(x(1,1)/app.res_display(2)*app.res_display(2)/app.smalles_zone)+1);
            win = round(app.TamaodeseleccinSlider.Value);                     %Tamaño de la ventana de ajuste
            
            rows_first= row_adjust-win;%Ajuste de la ventana del primer y último índice de las columnas que se van a seleccionar 
            rows_last = row_adjust+win

            rows_first(rows_first <= 0) = 1; %Ajuste de índices para no exceder límites de array
            rows_last(rows_last <= 0) = 1;
            rows_last(rows_last > size(app.grid_small, 1)) = size(app.grid_small, 1);
            
            
            cols_first= col_adjust-win;%Ajuste de la ventana del primer y último índice de las filas que se van a seleccionar 
            cols_last = col_adjust+win
            
            cols_first(cols_first <= 0) = 1;%Ajuste de índices para no exceder límites de array
            cols_last(cols_last <= 0) = 1;
            cols_last(cols_last > size(app.grid_small, 2)) = size(app.grid_small, 2);
            
            rows = rows_first:rows_last%Generación de vector con los índices que se van a modificar para filas y columnas
            cols = cols_first:cols_last
            app.grid_small(rows,cols) = app.SensibilidadKnob.Value; %Asignación del valor de sensibilidad de la ruleta de ajuste
            
            actualizar_fondo_avanzado; %Actualizar y mostrar representación de color a nueva matriz de sensibilidades
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AjusteporzonasUIFigure = uifigure('Visible', 'off');
            app.AjusteporzonasUIFigure.Name = 'Ajuste por zonas';
            app.AjusteporzonasUIFigure.CloseRequestFcn = createCallbackFcn(app, @AjusteporzonasUIFigureCloseRequest, true);
            app.AjusteporzonasUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @AjusteporzonasUIFigureWindowButtonDown, true);
            app.LimpiarzonasButton = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.LimpiarzonasButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarzonasButtonPushed, true);
            app.LimpiarzonasButton.Text = {'Limpiar'; 'zonas'; ''};
            app.MarcartodasButton = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.MarcartodasButton.ButtonPushedFcn = createCallbackFcn(app, @MarcartodasButtonPushed, true);
            app.MarcartodasButton.Text = {'Marcar'; 'todas'};
            app.flecha_arriba = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.flecha_arriba.ButtonPushedFcn = createCallbackFcn(app, @flecha_arribaPushed, true);
            app.flecha_arriba.Text = {'🢁'; ''};
            app.flecha_abajo = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.flecha_abajo.ButtonPushedFcn = createCallbackFcn(app, @flecha_abajoButtonPushed, true);
            app.flecha_abajo.Text = '🢃';
            app.flecha_izquierda = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.flecha_izquierda.ButtonPushedFcn = createCallbackFcn(app, @flecha_izquierdaPushed, true);
            app.flecha_izquierda.Text = '🢀';
            app.flecha_derecha = uibutton(app.AjusteporzonasUIFigure, 'push');
            app.flecha_derecha.ButtonPushedFcn = createCallbackFcn(app, @flecha_derechaButtonPushed, true);
            app.flecha_derecha.Text = '🢂';
            app.SensibilidadKnobLabel = uilabel(app.AjusteporzonasUIFigure);
            app.SensibilidadKnobLabel.Text = 'Sensibilidad';
            app.SensibilidadKnob = uiknob(app.AjusteporzonasUIFigure, 'continuous');
            app.SensibilidadKnob.Limits = [0 10];
            app.UIAxes = uiaxes(app.AjusteporzonasUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TamaodeseleccinSliderLabel = uilabel(app.AjusteporzonasUIFigure);
            app.TamaodeseleccinSliderLabel.Text = 'Tamaño de selección';
            app.TamaodeseleccinSlider = uislider(app.AjusteporzonasUIFigure);
            app.TamaodeseleccinSlider.Limits = [0 4];
            app.TamaodeseleccinSlider.MajorTicks = [0 1 2 3 4];
            app.TamaodeseleccinSlider.MajorTickLabels = {'1/576', '1/64', '1/23', '1/12', '1/7'};
            app.TamaodeseleccinSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.AjusteporzonasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = advanced_grid_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AjusteporzonasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AjusteporzonasUIFigure)
        end
    end
end