classdef grid_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        LimpiarzonasButton  matlab.ui.control.Button
        MarcartodasButton   matlab.ui.control.Button
        flecha_arriba       matlab.ui.control.Button
        flecha_abajo        matlab.ui.control.Button
        flecha_izquierda    matlab.ui.control.Button
        flecha_derecha      matlab.ui.control.Button
    end

    
    properties (Access = public)
        video_object; % Description
        grid_small = zeros(18,32);       %La rejilla que represnta las posiciones de los botones;
        size_background = [360 640];
    end
    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function click_en_imagen(app,source,event)
            nombre = source.ImageSource;
            k = strfind(nombre,'_');
            d = strfind(nombre,'.');
            fila = str2num(nombre(k(1)+1:k(2)-1));
            columna = str2num(nombre(k(2)+1:d(1)-1));
            if app.grid_small(fila, columna) == 0
                app.grid_small(fila, columna) = 1;
            else
                app.grid_small(fila, columna) = 0;
            end
            
           actualizar_fondo;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vidobj)
            %imresize(grid_small, [240 320],'Antialiasing',false) > 0.5; % Description
            PosFirsttButton = [59 441];
            button_size = 20;
            
            
            app.video_object = vidobj;
            img = getsnapshot(vidobj);
            imgback = ycbcr2rgb(imresize(img,app.size_background));
            app.Image.ImageSource = imgback;

            
            %==========================================
            %=   Generación de matriz de botones      =
            %==========================================
            A = [PosFirsttButton button_size button_size];  %Posición del primer botón(esquina superior izquierda)
            n_rows_button = app.size_background(1)/button_size;
            n_cols_button = app.size_background(2)/button_size;
            
            for i = 1:n_cols_button       %Bucle para columna
                for j = 1:n_rows_button   %Bucle para fila
                    UniButton(i+j) = uiimage(app.UIFigure,'ImageSource',fullfile(strcat('GRID/',['s_',num2str(j),'_',num2str(i),'.png'])));  %Creando objeto imagen
                    UniButton(i+j).Position = A;  %Dando posición al botón
                    UniButton(i+j).ImageClickedFcn = @app.click_en_imagen;  %Vínculo con manejador de Click sobre imagen
                    A(2) = A(2) - button_size; %Cambio de posición para el siguiente botón
                end
                A(2) = PosFirsttButton(2);
                A(1) = A(1) + button_size;
            end
            
            
        end

        % Callback function
        function im1_1Clicked(app, event)
            if strcmp(app.im1_1.ImageSource,'verde.png')
                app.im1_1.ImageSource = 'rojo.png';
            else
                app.im1_1.ImageSource = 'verde.png';
            end
        end

        % Button pushed function: LimpiarzonasButton
        function LimpiarzonasButtonPushed(app, event)
            app.grid_small = zeros(18,32);
            actualizar_fondo;
        end

        % Button pushed function: MarcartodasButton
        function MarcartodasButtonPushed(app, event)
            app.grid_small = ones(18,32);
            actualizar_fondo;
        end

        % Button pushed function: flecha_izquierda
        function flecha_izquierdaPushed(app, event)
            app.grid_small = [ones(18,16) zeros(18,16)];
            actualizar_fondo;
        end

        % Button pushed function: flecha_arriba
        function flecha_arribaPushed(app, event)
            app.grid_small = [ones(9,32);zeros(9,32)];
            actualizar_fondo;
        end

        % Button pushed function: flecha_derecha
        function flecha_derechaButtonPushed(app, event)
            app.grid_small = [zeros(18,16) ones(18,16)];
            actualizar_fondo;
        end

        % Button pushed function: flecha_abajo
        function flecha_abajoButtonPushed(app, event)
            app.grid_small = [zeros(9,32);ones(9,32)];
            actualizar_fondo;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            scaled_grid = imresize(app.grid_small, [240 320],'Antialiasing',false,"Method","nearest"); % Description 
           Videovigilancia.grid = scaled_grid;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.LimpiarzonasButton = uibutton(app.UIFigure, 'push');
            app.LimpiarzonasButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarzonasButtonPushed, true);
            app.LimpiarzonasButton.Text = {'Limpiar'; 'zonas'; ''};
            app.MarcartodasButton = uibutton(app.UIFigure, 'push');
            app.MarcartodasButton.ButtonPushedFcn = createCallbackFcn(app, @MarcartodasButtonPushed, true);
            app.MarcartodasButton.Text = {'Marcar'; 'todas'};
            app.flecha_arriba = uibutton(app.UIFigure, 'push');
            app.flecha_arriba.ButtonPushedFcn = createCallbackFcn(app, @flecha_arribaPushed, true);
            app.flecha_arriba.Text = {'🢁'; ''};
            app.flecha_abajo = uibutton(app.UIFigure, 'push');
            app.flecha_abajo.ButtonPushedFcn = createCallbackFcn(app, @flecha_abajoButtonPushed, true);
            app.flecha_abajo.Text = '🢃';
            app.flecha_izquierda = uibutton(app.UIFigure, 'push');
            app.flecha_izquierda.ButtonPushedFcn = createCallbackFcn(app, @flecha_izquierdaPushed, true);
            app.flecha_izquierda.Text = '🢀';
            app.flecha_derecha = uibutton(app.UIFigure, 'push');
            app.flecha_derecha.ButtonPushedFcn = createCallbackFcn(app, @flecha_derechaButtonPushed, true);
            app.flecha_derecha.Text = '🢂';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grid_control(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end