classdef notify_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DetectoractivadoUIFigure  matlab.ui.Figure
        UIAxes_prev               matlab.ui.control.UIAxes
        UIAxes_next               matlab.ui.control.UIAxes
        Image                     matlab.ui.control.Image
        AbrirdirectoriodelaalertaButton  matlab.ui.control.Button
        CerrarButton              matlab.ui.control.Button
        DETECTORACTIVADOLabel     matlab.ui.control.Label
        AlertL1Label              matlab.ui.control.Label
        UIAxes_info               matlab.ui.control.UIAxes
        AlertL2Label              matlab.ui.control.Label
        AlertL3Label              matlab.ui.control.Label
        ImagenanteriorLabel       matlab.ui.control.Label
        ImagenactualLabel         matlab.ui.control.Label
        DetallesavanzadosLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        alert_path % Cadena de caracteres que almacena la ruta en la que se almacena las imágenes de la alerta que ha ejecutado la instancia de esta app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, vidobj, n_frames, moving, img1, img2, img_prev, MSEmatrix, counter, tags, sizes, vector, x, y)
            DateString = datestr(datetime('now'));
            [status,msg] = mkdir('ALERTAS/',replace(DateString,':','-'));
            app.alert_path = ['ALERTAS/' replace(DateString,':','-')];
            fullFileNameGif = fullfile(strcat('ALERTAS/',replace(DateString,':','-')), 'detection.gif');
            
            [imind,cm] = rgb2ind(img_prev,256); 
            imwrite(imind,cm,fullFileNameGif,'gif', 'Loopcount',inf,'DelayTime',.3);
            [imind,cm] = rgb2ind(img1,256);
            imwrite(imind,cm,fullFileNameGif,'gif','WriteMode','append','DelayTime',.3);
            [imind,cm] = rgb2ind(img2,256);
            imwrite(imind,cm,fullFileNameGif,'gif','WriteMode','append','DelayTime',.3); 
            
            for i=1:n_frames
                baseFileName = sprintf('Image #%d.png', i);
                fullFileName = fullfile(strcat('ALERTAS/',replace(DateString,':','-')), baseFileName);
                img= getsnapshot(vidobj);
                imwrite(img, fullFileName);
                [imind,cm] = rgb2ind(img,256);
                imwrite(imind,cm,fullFileNameGif,'gif','WriteMode','append','DelayTime',.3); 

            end
            
            I = imshow(img1, 'Parent', app.UIAxes_prev);%Mostrar imagen en visor
            %Inicialización del tamaño de ejes para ajuste con el sensor
            app.UIAxes_prev.XLim = [0 I.XData(2)];
            app.UIAxes_prev.YLim = [0 I.YData(2)];
            
            I = imshow(img2, 'Parent', app.UIAxes_next);%Mostrar imagen en visor
            %Inicialización del tamaño de ejes para ajuste con el sensor
            app.UIAxes_next.XLim = [0 I.XData(2)];
            app.UIAxes_next.YLim = [0 I.YData(2)];
            
            app.Image.ImageSource = fullFileNameGif;
            
            
            %====================================================================================
            %       Casuística para la descripción de la alerta
            %======================================================================================
            meanMSE=mean(mean(MSEmatrix));
            percent = (counter/numel(MSEmatrix))*100;
            
            if(numel(tags)==1)
                %==============================================================================================================================
                %Caso de detección simple
                app.AlertL1Label.Text = ['Variación resultante en un ECM de ' num2str(meanMSE) ' (' num2str(percent,2) '% de bloques activos)' ];
                app.AlertL2Label.Text = '';
                app.AlertL3Label.Text = '';
                
                resolution = size(img2,1:2);
                img_superponer =imresize(moving,resolution,"Method","nearest").*50;%Pinta de rojo levemente los bloques activados
                img_detec = img2;
                img_detec(:,:,1) = img_detec(:,:,1) + img_superponer;
                img_info = img_detec;
                fullFileName = fullfile(strcat('ALERTAS/',replace(DateString,':','-')), 'detection-blocks.png');
                imwrite(uint8(img_detec), fullFileName);
                
            elseif (numel(vector)==1)
                %==============================================================================================================================
                %Caso de detección con tamaño de ojeto y reconocimiento de formas
                app.AlertL1Label.Text = ['Variación resultante en un ECM de ' num2str(meanMSE) '(' num2str(percent,2) '% de bloques activos)' ];
                app.AlertL2Label.Text = ['en la detección de objetos, ' num2str(numel(sizes)) ' se ha(n) detectado con un tamaño mayor (rojo) del ' num2str(sizes(1)*100,2)  '%'];
                app.AlertL3Label.Text = '';
                %Almacenar también indicador del objeto que se ha detectado si se
                %introducen los argumentos necesarios
                im_object=img2;
                im_object(:,:,1) = im_object(:,:,1) +80.*imresize(uint8(tags==1),size(img2,1:2));
                im_object(:,:,2) = im_object(:,:,2) +80.*imresize(uint8(tags==2),size(img2,1:2));
                im_object(:,:,3) = im_object(:,:,3) +80.*imresize(uint8(tags==3),size(img2,1:2));
                pos = regionprops(true(size(tags)), tags, 'WeightedCentroid').WeightedCentroid;
                position = [pos(1)*size(img2,2)/size(tags,2) pos(2)*size(img2,1)/size(tags,1)];
                im_object_marker = insertMarker(im_object,position,'*','Size',20);
                
                img_info = im_object_marker;
        
                fullFileName = fullfile(strcat('ALERTAS/',replace(DateString,':','-')), 'Objeto_marcado.png');
                imwrite(im_object_marker, fullFileName);
            elseif(numel(vector)==2)
                %Caso de detección de dirección
                real = vector(1);
                imag = vector(2);
                angle_degree = angle(real+imag*i)*180/pi;  % Conversión a dirección en grados
                
                app.AlertL1Label.Text = ['Variación resultante en un ECM de ' num2str(meanMSE) '(' num2str(percent,2) '% de bloques activos)' ];
                app.AlertL2Label.Text = ['en la detección de objetos, ' num2str(numel(sizes)) ' se ha(n) detectado con un tamaño mayor (rojo) del ' num2str(sizes(1)*100,2)  '%'];
                app.AlertL3Label.Text = ['y se ha detectado movimiento en la dirección indicada'];
                %Almacenar también indicador del objeto que se ha detectado si se
                %introducen los argumentos necesarios
                im_object=img2;
                im_object(:,:,1) = im_object(:,:,1) +80.*imresize(uint8(tags==1),size(img2,1:2));
                im_object(:,:,2) = im_object(:,:,2) +80.*imresize(uint8(tags==2),size(img2,1:2));
                im_object(:,:,3) = im_object(:,:,3) +80.*imresize(uint8(tags==3),size(img2,1:2));
                pos = regionprops(true(size(tags)), tags, 'WeightedCentroid').WeightedCentroid;
                position1 = [x(1)*size(img2,2)/size(tags,2) x(2)*size(img2,1)/size(tags,1)];
                position2 = [y(1)*size(img2,2)/size(tags,2) y(2)*size(img2,1)/size(tags,1)];
                m = (position2(2)-position1(2))/(position2(1)-position1(1));
                b = position1(2)- m*position1(1);
                if position1(1) <= position2(1)
                    x = position1(1):0.5:position2(1);
                else
                    x = position2(1):0.5:position1(1);
                end
                
                y = m*x + b;
                points=[x' y'];
                
                im_object_marker = insertMarker(im_object,points,'circle','Size',5,"Color","red");
                
                
                im_object_marker = insertMarker(im_object_marker,position1,'circle','Size',15,"Color","red");
                im_object_marker = insertMarker(im_object_marker,position2,'circle','Size',15,"Color","magenta");
                
                
                img_info = im_object_marker;
        
                fullFileName = fullfile(strcat('ALERTAS/',replace(DateString,':','-')), 'Objeto_marcado.png');
                imwrite(im_object_marker, fullFileName);
            end
            
            I = imshow(img_info, 'Parent', app.UIAxes_info);%Mostrar imagen en visor
            %Inicialización del tamaño de ejes para ajuste con el sensor
            app.UIAxes_info.XLim = [0 I.XData(2)];
            app.UIAxes_info.YLim = [0 I.YData(2)];
               
                   
            pause(5);
            app.delete;
            
            
        end

        % Button pushed function: AbrirdirectoriodelaalertaButton
        function AbrirdirectoriodelaalertaButtonPushed(app, event)
            winopen(app.alert_path);
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DetectoractivadoUIFigure = uifigure('Visible', 'off');
            app.DetectoractivadoUIFigure.Name = '¡Detector activado!';
            app.UIAxes_prev = uiaxes(app.DetectoractivadoUIFigure);
            title(app.UIAxes_prev, '')
            xlabel(app.UIAxes_prev, '')
            ylabel(app.UIAxes_prev, '')
            app.UIAxes_prev.XTick = [];
            app.UIAxes_prev.YTick = [];
            app.UIAxes_next = uiaxes(app.DetectoractivadoUIFigure);
            title(app.UIAxes_next, '')
            xlabel(app.UIAxes_next, '')
            ylabel(app.UIAxes_next, '')
            app.UIAxes_next.XTick = [];
            app.UIAxes_next.YTick = [];
            app.Image = uiimage(app.DetectoractivadoUIFigure);
            app.AbrirdirectoriodelaalertaButton = uibutton(app.DetectoractivadoUIFigure, 'push');
            app.AbrirdirectoriodelaalertaButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirdirectoriodelaalertaButtonPushed, true);
            app.AbrirdirectoriodelaalertaButton.Text = {'Abrir directorio de'; ' la alerta'};
            app.CerrarButton = uibutton(app.DetectoractivadoUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.DETECTORACTIVADOLabel = uilabel(app.DetectoractivadoUIFigure);
            app.DETECTORACTIVADOLabel.Text = '⚠ DETECTOR ACTIVADO ⚠';
            app.AlertL1Label = uilabel(app.DetectoractivadoUIFigure);
            app.AlertL1Label.Text = 'AlertL1';
            app.UIAxes_info = uiaxes(app.DetectoractivadoUIFigure);
            title(app.UIAxes_info, '')
            xlabel(app.UIAxes_info, '')
            ylabel(app.UIAxes_info, '')
            app.UIAxes_info.XTick = [];
            app.UIAxes_info.YTick = [];
            app.AlertL2Label = uilabel(app.DetectoractivadoUIFigure);
            app.AlertL2Label.Text = 'AlertL2';
            app.AlertL3Label = uilabel(app.DetectoractivadoUIFigure);
            app.AlertL3Label.Text = 'AlertL3';
            app.ImagenanteriorLabel = uilabel(app.DetectoractivadoUIFigure);
            app.ImagenanteriorLabel.Text = 'Imagen anterior';
            app.ImagenactualLabel = uilabel(app.DetectoractivadoUIFigure);
            app.ImagenactualLabel.Text = 'Imagen actual';
            app.DetallesavanzadosLabel = uilabel(app.DetectoractivadoUIFigure);
            app.DetallesavanzadosLabel.Text = 'Detalles avanzados';
            % Show the figure after all components are created
            app.DetectoractivadoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = notify_detection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DetectoractivadoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DetectoractivadoUIFigure)
        end
    end
end